classdef HappyLife < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MeasureNowButton         matlab.ui.control.Button
        WelcometoHappyLifeLabel  matlab.ui.control.Label
        LetsmeasureyourskinconductivitytoseewhenyouwerestressedLabel  matlab.ui.control.Label
        ImportDataButton         matlab.ui.control.Button
        LivePlottingButton       matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportDataButton
        function ImportData(app, event)
            [file,path] = uigetfile('*.csv');  %select csv file through file explorer
            fn =strcat(path,file);
            identifier(fn) %run analysis on imported data
        end

        % Button pushed function: MeasureNowButton
        function MeasureNowButtonPushed(app, event)
            %calls python logger to start logging sensor data
            system('py stress_logger.py --port=COM6 --verbose &'); %your specific port name can be found by running the python script with argument '--help'
            pause(5) %wait for first 20 data points before allowing to liveplot
        end

        % Value changed function: LivePlottingButton
        function LivePlottingButtonValueChanged(app, event)
            value = app.LivePlottingButton.Value; %checks whether live plotting feature enabled
            while value == 1     %reads updated csv file and plots every 5 seconds
                value = app.LivePlottingButton.Value; %continually checking of liveplotting is enabled
                data =csvread('liveplotting.csv');
                plot(data);
                pause(5)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MeasureNowButton = uibutton(app.UIFigure, 'push');
            app.MeasureNowButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureNowButtonPushed, true);
            app.MeasureNowButton.Text = 'Measure Now';
            app.WelcometoHappyLifeLabel = uilabel(app.UIFigure);
            app.WelcometoHappyLifeLabel.Text = 'Welcome to HappyLife !';
            app.LetsmeasureyourskinconductivitytoseewhenyouwerestressedLabel = uilabel(app.UIFigure);
            app.LetsmeasureyourskinconductivitytoseewhenyouwerestressedLabel.Text = 'Lets measure your skin conductivity to see when you were stressed';
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportData, true);
            app.ImportDataButton.Text = 'Import Data';
            app.LivePlottingButton = uibutton(app.UIFigure, 'state');
            app.LivePlottingButton.ValueChangedFcn = createCallbackFcn(app, @LivePlottingButtonValueChanged, true);
            app.LivePlottingButton.Text = 'Live Plotting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HappyLife

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end